classdef climatewedges < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        WedgesEditFieldLabel          matlab.ui.control.Label
        WedgesEditField               matlab.ui.control.EditField
        SubmitButton                  matlab.ui.control.Button
        ConstraintsEditFieldLabel     matlab.ui.control.Label
        ConstraintsEditField          matlab.ui.control.EditField
        StrategyEditFieldLabel        matlab.ui.control.Label
        StrategyEditField             matlab.ui.control.EditField
        Lamp                          matlab.ui.control.Lamp
        Lamp2                         matlab.ui.control.Lamp
        Lamp2_2                       matlab.ui.control.Lamp
        progressLabel                 matlab.ui.control.Label
        mainPanel                     matlab.ui.container.Panel
        instructionLabel              matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
        Tab_2                         matlab.ui.container.Tab
        Tab_3                         matlab.ui.container.Tab
        Tab_4                         matlab.ui.container.Tab
        Lamp2_3                       matlab.ui.control.Lamp
        HistoricalDataEditFieldLabel  matlab.ui.control.Label
        HistoricalDataEditField       matlab.ui.control.EditField
        CurrentStateLabel             matlab.ui.control.Label
        StrategiesListBoxLabel        matlab.ui.control.Label
        StrategiesListBox             matlab.ui.control.ListBox
        StrategySelect                matlab.ui.control.CheckBox
        MainPlot                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        wedgeData = 0;
        constraintsData = 0;
        strategyData = 0;
        historicalData = 0;
        selectedWedges = 0;
        startYear = 2018;
        GtC_est = 0;
    end
    
    methods (Access = private)
        
        function output = readInFile(app,filepath,index)
            file = fopen(filepath,'r');
            fileData = {};
            header = fgetl(file);
            if index == 1
                delim = ';';
            elseif index == 4
                delim = '	';
            else
                delim = ' ';
            end
            header_p = strsplit(header,delim);
            temp_output = cell(1,2*length(header_p));
            temp_output(1:2:end) = header_p;
            if index == 4
                output = struct('Year',[],'GtCyr',[]);
            else
                output = struct(temp_output{1:end});
            end
            fields = fieldnames(output);
            while ~feof(file)
                curr_line = fgetl(file);
                curr_line_p = strsplit(curr_line,delim);
                
                for i = 1:length(curr_line_p)
                    if isempty(output.(fields{i}))
                        output.(fields{i}) = curr_line_p(i);
                    else
                        output.(fields{i})(end+1) = curr_line_p(i);
                    end
                end
            end
            
        end
        
    end
    
    methods (Access = public)
        
        function writeWedges(app)
            file  = fopen('output.txt','w');
            header = 'ID;StrategyName;Sector;NumberUsed;TotalCost;Challenges\n';
            fwrite(file,header);
            currentID = NaN;
            duplicates = [];
            %Filters out duplicate wedges
            for i = 1:length(app.selectedWedges)
                if isequal(app.selectedWedges.ID{i},currentID)
                    curr = duplicates(find(duplicates(1:2:end) == currentID)+1);
                    curr = curr + 1;
                else
                    currentID = app.selectedWedges.ID{i};
                    duplicates(end+1) = [app.selectedWedges.ID{i},1];
                end
            end
            %Writes each wedge + data to file
            for i = 1:2:length(duplicates)
                line = app.selectedWedges.ID{duplicates(i)} + app.selectedWedges.StrategyName{i} + app.selectedWedges.Sector{i} + duplicates(i+1) + 2 *duplicates(i+1) + app.selectedWedges.Challenges{i};
                fwrite(line+'\n');
            end
            fclose(file);
            
        end
        
        function plotHData(app)
            app.MainPlot.XLim = [1959,2068];
            years= cellfun(@str2double,app.historicalData.Year)
            GtCyr =cellfun(@str2double,app.historicalData.GtCyr)
            plot(app.MainPlot,years,GtCyr);
            hold(app.MainPlot);
            
        end
        
        function plotBAUData(app)
            
            years = 2018:1:2068;
            years_actual= (2017:1:2068);
            app.GtC_est = [10]
            t_prev =2017;
            %C_init = 800;
            E_init = 10;
            E= @(x) E_init * 2^((x-t_prev)./50);
            %C= @(x) C_init + (.5*E(x));
            for i = 1:length(years)
                if i == 1
                    app.GtC_est(end+1) = E(years(i));
                else
                    t_prev=  years(i)-1;
                    E_init = app.GtC_est(i-1);
                    app.GtC_est(end+1) = E(years(i));
                end
                
                
            end
            plot(app.MainPlot,years_actual,app.GtC_est);
            
            
        end
        
        function plotFlatline(app)
            years = app.startYear:1:2068;
            init_value = app.GtC_est(app.startYear-2017);
            GtC_flatline = ones(size(years)).*init_value;
            plot(app.MainPlot,years,GtC_flatline);
            
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            x=2;
            app.Lamp.Color = [ 1 0 0];
            app.Lamp2.Color = [ 1 0 0];
            app.Lamp2_2.Color = [1 0 0 ];
            app.Lamp2_3.Color = [1 0 0 ];
            
            
        end

        % Value changed function: WedgesEditField
        function WedgesEditFieldValueChanged(app, event)
            wedge = app.WedgesEditField.Value;
            
        end

        % Value changed function: ConstraintsEditField
        function ConstraintsEditFieldValueChanged(app, event)
            constraints = app.ConstraintsEditField.Value;
            
        end

        % Value changed function: StrategyEditField
        function StrategyEditFieldValueChanged(app, event)
            strategy = app.StrategyEditField.Value;
            
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            fail_p=  1;
            try
                if app.StrategySelect.Value == 1
                    fpaths = {app.WedgesEditField.Value;app.ConstraintsEditField.Value;app.StrategyEditField.Value;app.HistoricalDataEditField.Value};
                    fail_p=  1;
                    app.wedgeData = app.readInFile(fpaths{1},1);
                    fail_p=  2;
                    app.constraintsData = app.readInFile(fpaths{2},2);
                    fail_p=  3;
                    app.strategyData = app.readInFile(fpaths{3},3);
                    fail_p = 4;
                    app.historicalData = app.readInFile(fpaths{4},4);
                    
                    app.Lamp.Color = [ 0 1 0];
                    app.Lamp2.Color = [ 0 1 0];
                    app.Lamp2_2.Color = [0 1 0 ];
                    app.Lamp2_3.Color = [0 1 0 ];
                else
                    fpaths = {app.WedgesEditField.Value;app.ConstraintsEditField.Value;app.HistoricalDataEditField.Value};
                    fail_p=  1;
                    app.wedgeData = app.readInFile(fpaths{1},1);
                    fail_p=  2;
                    app.constraintsData = app.readInFile(fpaths{2},2);
                    fail_p=  3;
                    app.historicalData = app.readInFile(fpaths{3},4);
                    
                    app.Lamp.Color = [ 0 1 0];
                    app.Lamp2.Color = [ 0 1 0];
                    app.Lamp2_2.Color = [0 1 0 ];
                    
                end
                
                
                success = (msgbox('Success: All files read in','Success','modal'));
                Cos = app.wedgeData.Cost;
                IDs = app.wedgeData.ID;
                Cats = app.wedgeData.Category
                
                numIDs = numel(IDs);
                numCost = numel(Cos);
                IDlabel = uilabel(app.mainPanel,'Text',IDs);
                IDlabel_h = uilabel(app.mainPanel, 'Text', 'IDs');
                costlabel = uilabel(app.mainPanel, 'Text',Cos);
                costlabel_h = uilabel(app.mainPanel, 'Text', 'Costs');
                Catslabel = uilabel(app.mainPanel, 'Text', Cats);
                Catslabel_h = uilabel(app.mainPanel, 'Text', 'Categories');
                
                Catslabel.Position(3) = 25*numel(Cats);
                Catslabel.Position(4) = 25*numel(Cats);
                Catslabel.Position(1:2) = [75 -10];
                Catslabel_h.Position(1:2) = [75 0];
                Catslabel_h.Position(4) = 25*numel(Cats);
                Catslabel_h.Position(3) = 60;
                sizeID = IDlabel.Position(3:4);
                IDlabel_h.Position(1:2) = [15 0];
                
                IDlabel.Position(1) = 25;
                IDlabel.Position(2) = -10;
                IDlabel.Position(4) = 25*numIDs;
                sizeID(1,2) = 2*numCost;
                
                sizecost = costlabel.Position(3:4);
                costlabel_h.Position(1:2) = [40 0];
                sizecost(1,2) = 2*numIDs;
                costlabel.Position(1) = 50;
                costlabel.Position(2) =  -10;
                costlabel.Position(4) =  25*numCost;


                delete(app.WedgesEditField)
                delete(app.WedgesEditFieldLabel)
                delete(app.ConstraintsEditField)
                delete(app.ConstraintsEditFieldLabel)
                delete(app.StrategyEditField)
                delete(app.StrategyEditFieldLabel)
                delete(app.HistoricalDataEditField)
                delete(app.HistoricalDataEditFieldLabel)
                delete(app.instructionLabel)
                delete(app.Lamp)
                delete(app.Lamp2)
                delete(app.Lamp2_2)
                delete(app.Lamp2_3)
                
                
                plotHData(app);
                plotBAUData(app);
                plotFlatline(app);
            catch
                error = msgbox(sprintf('Invalid Filename: filepath %d',fail_p), 'Error','error');
            end
            %Wedge Display setup
            %disp(app.wedgeData)


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: StrategiesListBox
        function StrategiesListBoxValueChanged(app, event)
            value = app.StrategiesListBox.Value
            Selections = {'Efficiency & Conservation','Fossil-Fuel Reductions','Nuclear Energy','Biostorage & Renewables'};
            %                if     value == string(Selections(1))
            %                    Cats(
            %                elseif value == string(Selections(2))
            %
            %                elseif value == string(Selections(3))
            %
            %                elseif value == string(Selections(4))
            %                end
            
        end

        % Value changed function: StrategySelect
        function StrategySelectValueChanged(app, event)
            value = app.StrategySelect.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.WedgesEditFieldLabel = uilabel(app.UIFigure);
            app.WedgesEditFieldLabel.Text = 'Wedges';
            app.WedgesEditField = uieditfield(app.UIFigure, 'text');
            app.WedgesEditField.ValueChangedFcn = createCallbackFcn(app, @WedgesEditFieldValueChanged, true);
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.ConstraintsEditFieldLabel = uilabel(app.UIFigure);
            app.ConstraintsEditFieldLabel.Text = 'Constraints';
            app.ConstraintsEditField = uieditfield(app.UIFigure, 'text');
            app.ConstraintsEditField.ValueChangedFcn = createCallbackFcn(app, @ConstraintsEditFieldValueChanged, true);
            app.StrategyEditFieldLabel = uilabel(app.UIFigure);
            app.StrategyEditFieldLabel.Text = 'Strategy';
            app.StrategyEditField = uieditfield(app.UIFigure, 'text');
            app.StrategyEditField.ValueChangedFcn = createCallbackFcn(app, @StrategyEditFieldValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp2 = uilamp(app.UIFigure);
            app.Lamp2_2 = uilamp(app.UIFigure);
            app.progressLabel = uilabel(app.UIFigure);
            app.progressLabel.Text = '1) Input Source files';
            app.mainPanel = uipanel(app.UIFigure);
            app.mainPanel.Title = 'main';
            app.instructionLabel = uilabel(app.UIFigure);
            app.instructionLabel.Text = '*Enter names of the 3 or (4) files ';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_4 = uitab(app.TabGroup);
            app.Lamp2_3 = uilamp(app.UIFigure);
            app.HistoricalDataEditFieldLabel = uilabel(app.UIFigure);
            app.HistoricalDataEditFieldLabel.Text = 'Historical Data';
            app.HistoricalDataEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentStateLabel = uilabel(app.UIFigure);
            app.CurrentStateLabel.Text = '    Current State:';
            app.StrategiesListBoxLabel = uilabel(app.UIFigure);
            app.StrategiesListBoxLabel.Text = 'Strategies:';
            app.StrategiesListBox = uilistbox(app.UIFigure);
            app.StrategiesListBox.Items = {'Efficiency & Conservation', 'Fossil-Fuel Reductions', 'Nuclear Energy', 'Biostorage & Renewables'};
            app.StrategiesListBox.ValueChangedFcn = createCallbackFcn(app, @StrategiesListBoxValueChanged, true);
            app.StrategiesListBox.Value = 'Nuclear Energy';
            app.StrategySelect = uicheckbox(app.UIFigure);
            app.StrategySelect.ValueChangedFcn = createCallbackFcn(app, @StrategySelectValueChanged, true);
            app.StrategySelect.Text = '';
            app.MainPlot = uiaxes(app.UIFigure);
            xlabel(app.MainPlot, 'Years')
            ylabel(app.MainPlot, 'GtC/yr')
        end
    end

    methods (Access = public)

        % Construct app
        function app = climatewedges

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end